classdef proyectoCriptografia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DesplazamientoTab              matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        ModoButtonGroup_2              matlab.ui.container.ButtonGroup
        AnlisisButton_6                matlab.ui.control.RadioButton
        DesencriptarButton_2           matlab.ui.control.RadioButton
        EncriptarButton_2              matlab.ui.control.RadioButton
        AnlisisPanel                   matlab.ui.container.Panel
        AnalizarButton                 matlab.ui.control.Button
        AnalisisUITable                matlab.ui.control.Table
        CifradoPanel                   matlab.ui.container.Panel
        LimpiarButton                  matlab.ui.control.Button
        ClaveEditField                 matlab.ui.control.NumericEditField
        ClaveEditFieldLabel            matlab.ui.control.Label
        DescifrarButton                matlab.ui.control.Button
        CifrarButton                   matlab.ui.control.Button
        TextocifradoTextArea           matlab.ui.control.TextArea
        TextocifradoTextAreaLabel      matlab.ui.control.Label
        TextoclaroTextArea             matlab.ui.control.TextArea
        TextoclaroTextAreaLabel        matlab.ui.control.Label
        SustitucinTab                  matlab.ui.container.Tab
        Button_4                       matlab.ui.control.Button
        ClavePanel                     matlab.ui.container.Panel
        GenerarclaveButton             matlab.ui.control.Button
        ClaveUITable                   matlab.ui.control.Table
        CifradoPanel_5                 matlab.ui.container.Panel
        LimpiarButton_3                matlab.ui.control.Button
        DescifrarButton_5              matlab.ui.control.Button
        CifrarButton_5                 matlab.ui.control.Button
        TextocifradoTextArea_4         matlab.ui.control.TextArea
        TextocifradoTextArea_4Label    matlab.ui.control.Label
        TextoclaroTextArea_4           matlab.ui.control.TextArea
        TextoclaroTextArea_4Label      matlab.ui.control.Label
        ModoButtonGroup_4              matlab.ui.container.ButtonGroup
        EncriptarButton_4              matlab.ui.control.RadioButton
        DesencriptarButton_4           matlab.ui.control.RadioButton
        AfnTab                         matlab.ui.container.Tab
        Button_2                       matlab.ui.control.Button
        ModoButtonGroup_3              matlab.ui.container.ButtonGroup
        AnlisisButton_7                matlab.ui.control.RadioButton
        DesencriptarButton_3           matlab.ui.control.RadioButton
        EncriptarButton_3              matlab.ui.control.RadioButton
        AnlisisPanel_2                 matlab.ui.container.Panel
        AnalizarButton_2               matlab.ui.control.Button
        AnalisisUITable_2              matlab.ui.control.Table
        CifradoPanel_2                 matlab.ui.container.Panel
        ClaveEditField_3               matlab.ui.control.NumericEditField
        BLabel                         matlab.ui.control.Label
        LimpiarButton_2                matlab.ui.control.Button
        ClaveAEditField                matlab.ui.control.NumericEditField
        ClaveALabel                    matlab.ui.control.Label
        DescifrarButton_2              matlab.ui.control.Button
        CifrarButton_2                 matlab.ui.control.Button
        TextocifradoTextArea_2         matlab.ui.control.TextArea
        TextocifradoTextArea_2Label    matlab.ui.control.Label
        TextoclaroTextArea_2           matlab.ui.control.TextArea
        TextoclaroTextArea_2Label      matlab.ui.control.Label
        HillTextoTab                   matlab.ui.container.Tab
        Button_3                       matlab.ui.control.Button
        ModoButtonGroup_5              matlab.ui.container.ButtonGroup
        DesencriptarButton_5           matlab.ui.control.RadioButton
        AnlisisButton_8                matlab.ui.control.RadioButton
        EncriptarButton_5              matlab.ui.control.RadioButton
        CifradoPanel_3                 matlab.ui.container.Panel
        LimpiarButton_4                matlab.ui.control.Button
        ClaveLabel_2                   matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        AnlisisButton_3                matlab.ui.control.Button
        TClaveEditField                matlab.ui.control.NumericEditField
        DimensindelamatrizLabel        matlab.ui.control.Label
        DescifrarButton_3              matlab.ui.control.Button
        CifrarButton_3                 matlab.ui.control.Button
        TextocifradoTextArea_3         matlab.ui.control.TextArea
        TextocifradoTextArea_3Label    matlab.ui.control.Label
        TextoclaroTextArea_3           matlab.ui.control.TextArea
        TextoclaroTextArea_3Label      matlab.ui.control.Label
        HillImagenTab                  matlab.ui.container.Tab
        ClavePanel_2                   matlab.ui.container.Panel
        vectorinicialIVCheckBox        matlab.ui.control.CheckBox
        TClaveEditField_2              matlab.ui.control.NumericEditField
        DimensindelamatrizLabel_2      matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        Label_2                        matlab.ui.control.Label
        Hyperlink_4                    matlab.ui.control.Hyperlink
        Hyperlink_3                    matlab.ui.control.Hyperlink
        Hyperlink_2                    matlab.ui.control.Hyperlink
        Hyperlink                      matlab.ui.control.Hyperlink
        Button_6                       matlab.ui.control.Button
        ModoButtonGroup                matlab.ui.container.ButtonGroup
        EncriptarButton                matlab.ui.control.RadioButton
        CifradoPanel_4                 matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        ImagencifradaLabel             matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        CifrarButton_4                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        CargarimagenButton             matlab.ui.control.Button
        VigenereTab                    matlab.ui.container.Tab
        Button_5                       matlab.ui.control.Button
        CifradoPanel_Vigenere          matlab.ui.container.Panel
        mximo15caracteresLabel         matlab.ui.control.Label
        AnlisisButton_9                matlab.ui.control.Button
        ClaveEditField_Vigenere        matlab.ui.control.EditField
        ClaveEditField_4Label          matlab.ui.control.Label
        LimpiarButton_Vigenere         matlab.ui.control.Button
        DescifrarButton_Vigenere       matlab.ui.control.Button
        CifrarButton_Vigenere          matlab.ui.control.Button
        TextocifradoTextArea_Vigenere  matlab.ui.control.TextArea
        TextocifradoTextArea_5Label    matlab.ui.control.Label
        TextoclaroTextArea_Vigenere    matlab.ui.control.TextArea
        TextoclaroTextArea_5Label      matlab.ui.control.Label
        ModoButtonGroup_Vigenere       matlab.ui.container.ButtonGroup
        AnlisisButton_Vigenere         matlab.ui.control.RadioButton
        DesencriptarButton_Vigenere    matlab.ui.control.RadioButton
        EncriptarButton_Vigenere       matlab.ui.control.RadioButton
    end

    
    methods (Access = public)
        
        function textoCifradoD = cifrarD(app,message,key)
            if message == ""
                textoCifradoD = "";
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                ptext_ = textToNum(app,message);
                f = @(x) mod(x + key, 26);
                ctext = f(ptext_);
                for l=1: size(ctext,2)
                    ctext_(l) = abet(ctext(l)+1);
                end
                textoCifradoD = ctext_;
            end
        end
        
        function ptext = textToNum(~,message)
            % convertir mensaje en una secuencia numérica

            abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            str = convertStringsToChars(strjoin(string(message)));
            % eliminar todos los caracteres que no pertenecen al alfabeto
            message = regexprep(str,'[^a-zA-Z\s]',''); 
            match = [" ", "\n"];
            % eliminar espacios y saltos de línea
            message = erase(upper(join(message)),match);
            ptext = num2cell(char(message));
            ptext_ = zeros(size(message));
            for l=1:size(ptext,2)
                ptext_(l) = strfind(abet,ptext(l))-1;
            end
            ptext = ptext_;
        end
        
    end
    
    methods (Access = public)
        
        function textoDescifradoD = descifrarD(app,message,key)
            if message == ""
                textoDescifradoD = "";
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                ptext_ = textToNum(app,message);
                f = @(x) mod(x - key, 26);
                ctext = f(ptext_);
                for l=1: size(ctext,2)
                    ctext_(l) = abet(ctext(l)+1);
                end
                textoDescifradoD = ctext_;
            end
        end
    end
    
    methods (Access = private)
        
        function textoCifradoA = cifrarA(app,message,key_a,key_b)
            if message == ""
                textoCifradoA = "";
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                ptext_ = textToNum(app,message);
                f = @(x) mod(key_a*x+key_b, 26);
                if gcd(key_a,26) == 1
                    ctext = f(ptext_);
                    for l=1: size(ctext,2)
                        ctext_(l) = abet(ctext(l)+1);
                    end
                    textoCifradoA = ctext_;
                else
                    textoCifradoA = "";
                    errordlg("El coeficiente A debe ser primo relativo de 26" + newline + "valores permitidos: 1,3,5,7,9,11,15,17,19,21,23,25", "Clave ilegal");
                end
            end
        end
        
        function textoDescifradoA = descifrarA(app,message,key_a,key_b)
            if message == ""
                textoDescifradoA = "";
            else
                if gcd(key_a,26) == 1
                    abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                    ptext_ = textToNum(app,message);
                    f = @(y) mod(y*key_a,26);
                    y = 1;
                    while f(y)~=1
                        y=y+1;
                    end
                    f = @(x) mod(y*(x-key_b),26);
                    ctext = f(ptext_);
                    for l=1: size(ctext,2)
                        ctext_(l) = abet(ctext(l)+1);
                    end
                    textoDescifradoA = ctext_;
                else
                    textoDescifradoA = ""; 
                    errordlg("El coeficiente A debe ser primo relativo de 26" + newline + "valores permitidos: 1,3,5,7,9,11,15,17,19,21,23,25", "Clave ilegal");
                end
            end
        end
        
        function freqT = analisisFreq(~,message)
            if message == ""
                freqT = [];
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                message = strcat(message,abet); % se agregan todas las letras al final
                                                % para que la tabla de frecuencias incluya
                                                % todas las letras
                message = string(message);
                message = erase(upper(message),' ');
                ptext = num2cell(char(message));
                ptext_ = zeros(size(message));
                
                mono = containers.Map;
                for l=1:size(ptext,2)
                    % MONOGRAMAS -------------------------------------
                    if isKey(mono,ptext(l))
                        mono(string(ptext(l))) = mono(string(ptext(l))) + 1;
                    else
                        mono(string(ptext(l))) = 1;
                    end
%                     % DIGRAMAS ---------------------------------------
%                     if l<=size(ptext,2)-1
%                         text = string(strcat(ptext(l),ptext(l+1)));
%                         if isKey(mono,text)
%                             mono(text) = mono(text) + 1;
%                         else
%                             mono(text) = 1;
%                         end
%                     end
%                     % TRIGRAMAS --------------------------------------
%                     if l<=size(ptext,2)-2
%                         text3 = string(strcat(strcat(ptext(l),ptext(l+1)),ptext(l+2)));
%                         if isKey(mono,text3)
%                             mono(text3) = mono(text3) + 1;
%                         else
%                             mono(text3) = 1;
%                         end
%                     end
                end
                Data = {};
                n = 1;
                for k = keys(mono)
                    if mono(k{1}) ~= 1 || strlength(k{1})==1
                        Data{n,1} = k{1};
                        Data{n,2} = mono(k{1})-1;
                        n = n+1;
                    end
                end
                freqT = Data;
            end
        end
        
        function textoCifradoH = cifrarH(~,message,key)
            if message == ""
                textoCifradoH = "";
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                message = string(message);
                match = [" ", "\n"];
                message = erase(upper(join(message)),match);
                ptext = num2cell(char(message));
                ptext_ = zeros(size(message));
                for l=1:size(ptext,2)
                    ptext_(l) = strfind(abet,ptext(l))-1;
                end
                A = key;
                val = int8(mod(det(A),26));
                
                if gcd(val,26) == 1
                    if mod(size(ptext_,2),size(A,1))==0
                        rowsize = size(A,1);
                        numrows = numel(ptext_)/rowsize;
                        pmat = reshape(ptext_,rowsize,numrows)';
                        cmat = mod(pmat * A,26);
                        ctext = reshape(cmat',1,numel(cmat'));
                        for l=1: size(ctext,2)
                            ctext_(l) = abet(ctext(l)+1);
                        end
                        textoCifradoH = ctext_;
                    else
                        textoCifradoH = "";
                        errordlg("texto claro no válido", "Error");
                    end
                else
                    textoCifradoH = "";
                    errordlg("clave ilegal", "Error");
                end
            end            
        end
        
        
        function textoDescifradoH = descifrarH(~,message,key)
            if message == ""
                textoDescifradoH = "";
            else
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                message = string(message);
                message = erase(upper(message),' ');
                ptext = num2cell(char(message));
                if mod(size(ptext,2),size(key,2)) == 0
                    ptext_ = zeros(size(message));
                    for l=1:size(ptext,2)
                        ptext_(l) = strfind(abet,ptext(l))-1;
                    end
                    A = key;
                    val = int8(mod(det(A),26));
                    if gcd(val,26) == 1
                        rowsize = size(A,1);
                        numrows = numel(ptext_)/rowsize;
                        cmat = reshape(ptext_,rowsize,numrows)';
                        n = 1;
                        while mod(n*det(A),26)~=1
                            n=n+1;
                        end
                        L1 = (A)^(-1)*det(A);
                        V = n*mod(L1,26);
                        pmat = int8(mod(cmat*(mod(V, 26)), 26));
                        ctext = reshape(pmat',1,numel(pmat'));
                        for l=1: size(ctext,2)
                            ctext_(l) = abet(ctext(l)+1);
                        end
                        textoDescifradoH = ctext_;
                    else
                        textoDescifradoH = "";
                        errordlg("clave ilegal", "Error");
                    end
                else
                    textoDescifradoH = "";
                    errordlg("la longitud del texto no coindice con el tamaño de la matriz");
                end
            end 
        end
        
        function clave = analisisClave(app,messageE,messageD,m)
            if messageE == "" %|| messageD == ""
                clave = zeros(m,m);
            else
                ptext = textToNum(app,messageE);
                if mod(size(ptext,2),m) == 0
                    pmat = reshape(ptext,m,[])';
                    pmat_ = pmat(1:m,1:m);
                    val = int8(mod(det(pmat_),26));
                    if gcd(val,26) == 1
                        ctext = textToNum(app,messageD);
                        if size(ctext,2) == size(ptext,2)
                            cmat = reshape(ctext,m,[])';
                            cmat_ = cmat(1:m,1:m);
                            n = 1;
                            while mod(n*det(cmat_),26)~=1
                                n=n+1;
                            end
                            L1 = (cmat_)^(-1)*det(cmat_);
                            V = n*mod(L1,26);
                            clave = round(mod(mod(V, 26)*pmat_, 26));
                        else
                            clave = zeros(m,m);
                            errordlg("la longitud del texto claro y el texto cifrado no coindicen", "Error");
                        end
                        
                    else
                        clave = zeros(m,m);
                        errordlg("Matriz no invertible", "error");
                    end
                else
                    clave = zeros(m,m);
                    errordlg("longitud del texto no corresponde con tamaño de la matriz", "error");
                end
            end 
        end
        
        
        function textoCifradoS = cifrarS(~,message,key)
            if message == ""
                textoCifradoS = "";
            else
                s_key = size(convertStringsToChars(key));
                if s_key(2) ~= 26
                    textoCifradoS = "";
                    errordlg("clave incompleta", "Error en la clave");
                else
                    
                    key = convertStringsToChars(key);

                    abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                    str = convertStringsToChars(strjoin(string(message)));
                    message = regexprep(str,'[^a-zA-Z\s]',''); 
                    match = [" ", "\n"];
                    message = erase(upper(join(message)),match);

                    ptext = char(message);
                    s = size(ptext);
                    cif = {};
                    for n = 1:s(2)
                        pos = strfind(abet,ptext(n));
                        cif{n} = key(pos:pos);
                    end
                    textoCifradoS = strjoin(cif,'');
                end   
            end 
        end
        
        function textoDescifradoS = descifrarS(~,message,key) %sustitución
            if message == ""
                textoDescifradoS = "";
            else
                s_key = size(convertStringsToChars(key));
                if s_key(2) ~= 26
                    textoDescifradoS = "";
                    errordlg("clave incompleta", "Error en la clave");
                else
                    abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                    key = convertStringsToChars(key);
                    message = string(message);
                    message = erase(upper(message),' ');
                    ptext = char(message);
                    s = size(ptext);
                    dcif = {};
                    for n = 1:s(2)
                        pos = strfind(key,ptext(n));
                        dcif{n} = abet(pos:pos);
                    end
                    textoDescifradoS = strjoin(dcif,'');
                end   
            end 
        end
        
        function tablaTC = analisisAfin(app,message)
            if message == ""
                tablaTC = [];
            else
                tabla = [];
                key_a = 1;
                key_b = 1;
                keyA_list = {};
                keyB_list = {};
                message_list = {};
                num = 1;
                for k=1:26
                    if gcd(key_a,26) == 1
                        key_b = 1;
                        for j=1:26
                            textoDescifrado = descifrarA(app,message,key_a,key_b);
                            tabla{num,1} = key_a;
                            tabla{num,2} = key_b;
                            tabla{num,3} = textoDescifrado;
                            key_b = key_b + 1;
                            num = num +1;
                        end
                    end
                    key_a = key_a + 1;
                end
                tablaTC = tabla;
            end 
        end
        
        function textoCifradoV = cifrarV(app,message,key,mode)
            if message == ""
                textoCifradoV = "";
            else
                ptext = textToNum(app,message);
                
                % igualar longitud del texto y la clave
                clave = cell(1,ceil(size(ptext,2)/size(key,2)));
                clave(:) = {key};
               
                if strlength(clave(1)) > 15
                    errordlg("la longitud de la clave no puede ser mayor a 15","clave");
                    textoCifradoV = "";
                    return;
                end

                key_ = cell2mat(clave);
                k = textToNum(app,key_(1:size(ptext,2)));

                if (mode == 'cifrar')
                    % cifrar texto
                    ctext = mod( ptext + k, 26);
                else
                    % descifrar
                    ctext = mod( ptext - k, 26);
                end
                
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                for l=1: size(ctext,2)
                    ctext_(l) = abet(ctext(l)+1);
                end
                textoCifradoV = ctext_;
            end
        end
        
        function clave = analisisVigenere(app, message)
            if message == ""
                clave = "";
            else
                ptext = textToNum(app,message);
                
                abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
                message = string(message);
                match = [" ", "\n"];
                message = erase(upper(join(message)),match);
                message = convertStringsToChars(message);
                n = size(ptext,2);
                m = 1;
                posibleM = zeros(15,1);
                for k = 1:15 % máximo de longitud de la clave igual a 10
                    y = strings([m,1]);
                    j = 1;
                    suma = 0;
                    for i=1:n
                        y(j) = strcat(y(j),message(i)); % subcadenas y_i
                        if j == m
                            j = 1;
                        else
                            j = j+1;
                        end
                    end
                    
                    frecuencias = {};
                    for i=1:m
                        n_ = strlength(y(i));
                        % tabla de frecuencias de y_i
                        freqT = analisisFreq(app,y(i)); 
                        freq = cell2mat(freqT(:,2));
                        frecuencias{i} = freq;
                        sumFreq = sum(freq.*(freq-1));
                        Ic = sumFreq/(n_*(n_-1));
                        suma = suma + Ic;
                    end
                    posibleM(k) = round(suma/m,3)-0.065;
%                     if abs(round(suma/m,3)-0.065) <= 0.009
%                         % toma m como la primera longitud que se acerque 
%                         % lo suficiente al indice de coincidencia 0.065
%                         break
%                     else
%                         m = m+1;
%                     end
                    m = m + 1;
                end

                [N,J] = max(posibleM);
                m = J; 
                
                y = strings([m,1]);
                n = size(ptext,2);
                j = 1;
                for i=1:n
                    y(j) = strcat(y(j),message(i)); % subcadenas y_i
                    if j == m
                        j = 1;
                    else
                        j = j+1;
                    end
                end

                frecuencias = {};
                for i=1:m
                    n_ = strlength(y(i));
                    % tabla de frecuencias de y_i
                    freqT = analisisFreq(app,y(i)); 
                    freq = cell2mat(freqT(:,2));
                    frecuencias{i} = freq;
                end

                clave_ = zeros(m,1);
                prob = [.082 .015 .028 .043 .127 .022 .020 .061 .070 .002 .008 .040 .024 .067 .075 .019 .001 .060 .063 .091 .028 .010 .023 .001 .020 .001]';
                
                for k=1:m
                    n_ = strlength(y(k));
                    f = frecuencias{k};
                    freq = round(f/n_,3);
                    posiblesClaves = zeros(26,1);
                    for g = 0:25
                        sh = circshift(prob,g);
                        Mg = sum(abs(freq-sh));

                        posiblesClaves(g+1) = Mg;%abs(Mg-0.065);
                    end
                    % la clave con la menor diferencia será la letra clave
                    [M,I] = min(posiblesClaves);
                    clave_(k) = I;
                end
                clavee = {};
                for pos=1:m
                    clavee{pos} = abet(clave_(pos));
                end
                clave = strjoin(clavee,"");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ESTADO INICIAL DE CADA SECCIÓN
            % Hill
            t = [5 17; 8 3];
            app.UITable.Data = t;
            app.UITable.ColumnName = ({});
            app.TextocifradoTextArea_3.Editable = false;
            app.TextoclaroTextArea_3.Editable = true;
            app.UITable.ColumnEditable = true;
            app.CifrarButton_3.Enable = true;
            app.DescifrarButton_3.Enable = false;
            app.AnlisisButton_3.Enable = false;
            %app.AnlisisButton_Vigenere.Enable = false;
            
            app.CargarimagenButton.Enable = true;
            app.CifrarButton_4.Enable = true;
            clave = [9 4 6 2 5 2 10 6; 3 14 7 4 5 4 9 7; 15 6 5 3 6 11 9 18; 3 4 5 6 2 3 5 7; 2 3 4 8 7 5 23 4; 3 4 5 18 5 4 13 14; 12 2 8 5 6 16 3 8; 1 2 5 13 4 6 9 5];
            %clave = [9 4 6 2 5 3 2 6; 3 4 6 3 1 2 3 5; 2 3 5 3 5 8 9 5; 3 4 5 6 2 3 4 6; 2 3 4 8 6 5 4 3; 3 4 5 8 1 2 3 3; 2 2 3 4 6 8 3 2; 1 2 2 3 4 6 9 5];
            app.UITable_2.ColumnName = ({});
            app.UITable_2.Data = clave;
            app.UITable_2.ColumnWidth = {30,30,30,30,30,30,30,30};
            % Desplazamiento
            app.TextoclaroTextArea.Editable = true;
            app.TextocifradoTextArea.Editable = false;
            app.CifrarButton.Enable = true;
            app.DescifrarButton.Enable = false;
            app.AnalizarButton.Enable = false;
            % Afin
            app.TextoclaroTextArea_2.Editable = true;
            app.TextocifradoTextArea_2.Editable = false;
            app.CifrarButton_2.Enable = true;
            app.DescifrarButton_2.Enable = false;
            app.AnalizarButton_2.Enable = false;
            app.ClaveAEditField.Enable = true;
            app.ClaveEditField_3.Enable = true;
            app.AnalisisUITable_2.ColumnWidth = {30,30};

            abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            app.ClaveUITable.Data = [num2cell(char(abet))', repmat(" ",26,1)];
            app.ClaveUITable.ColumnFormat = ({[] num2cell(char(abet))});
            app.ClaveUITable.ColumnEditable = [false true];

            setGlobaly('')
            % Sustitución
            app.TextoclaroTextArea_4.Editable = true;
            app.TextocifradoTextArea_4.Editable = false;
            app.CifrarButton_5.Enable = true;
            app.DescifrarButton_5.Enable = false;
            % Vigenere
            app.TextoclaroTextArea_Vigenere.Editable = true;
            app.TextocifradoTextArea_Vigenere.Editable = false;
            app.CifrarButton_Vigenere.Enable = true;
            app.DescifrarButton_Vigenere.Enable = false;
            app.AnlisisButton_9.Enable = false;
            
        end

        % Button pushed function: CifrarButton
        function CifrarButtonPushed(app, event)
            msg = app.TextoclaroTextArea.Value;
            key = app.ClaveEditField.Value;
            app.TextocifradoTextArea.Value = cifrarD(app,string(msg),key);
        end

        % Value changed function: TextoclaroTextArea
        function TextoclaroTextAreaValueChanged(app, event)
            %value = app.TextoclaroTextArea.Value;
        end

        % Button pushed function: DescifrarButton
        function DescifrarButtonPushed(app, event)
            msg = app.TextocifradoTextArea.Value;
            key = app.ClaveEditField.Value;
            app.TextoclaroTextArea.Value = descifrarD(app,string(msg),key);
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            msg = app.TextocifradoTextArea.Value;
            if msg ~= ""
                Data = {};
                for n = 1:26
                    Data{n,1} = n;
                    Data{n,2} = descifrarD(app,string(msg),n);
                end
                app.AnalisisUITable.Data = Data;
            else
                app.AnalisisUITable.Data = [];
            end
        end

        % Button pushed function: CifrarButton_2
        function CifrarButton_2Pushed(app, event)
            msg = app.TextoclaroTextArea_2.Value;
            key_a = app.ClaveAEditField.Value;
            key_b = app.ClaveEditField_3.Value;
            app.TextocifradoTextArea_2.Value = cifrarA(app,string(msg),key_a,key_b);
        end

        % Value changed function: ClaveAEditField
        function ClaveAEditFieldValueChanged(app, event)
            value = app.ClaveAEditField.Value;
            
        end

        % Button pushed function: DescifrarButton_2
        function DescifrarButton_2Pushed(app, event)
            msg = app.TextocifradoTextArea_2.Value;
            key_a = app.ClaveAEditField.Value;
            key_b = app.ClaveEditField_3.Value;
            app.TextoclaroTextArea_2.Value = descifrarA(app,string(msg),key_a,key_b);
        end

        % Value changed function: TClaveEditField
        function TClaveEditFieldValueChanged(app, event)
            dim = app.TClaveEditField.Value;
            values = zeros(dim, dim);
            app.UITable.Data = values;
        end

        % Button pushed function: CifrarButton_3
        function CifrarButton_3Pushed(app, event)
            msg = app.TextoclaroTextArea_3.Value;
            key = app.UITable.Data;
            app.TextocifradoTextArea_3.Value = cifrarH(app,string(msg),key);
        end

        % Button pushed function: DescifrarButton_3
        function DescifrarButton_3Pushed(app, event)
            msg = app.TextocifradoTextArea_3.Value;
            key = app.UITable.Data;
            app.TextoclaroTextArea_3.Value = descifrarH(app,string(msg),key);            
        end

        % Button pushed function: AnlisisButton_3
        function AnlisisButton_3Pushed(app, event)
            msgE = app.TextocifradoTextArea_3.Value;
            msgD = app.TextoclaroTextArea_3.Value;
            m = app.TClaveEditField.Value;
            app.UITable.Data = analisisClave(app,msgE,msgD,m);
        end

        % Button pushed function: CargarimagenButton
        function CargarimagenButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.png';'*.jpeg'});
            imgD = imread(strcat(path,file));
            app.Image.ImageSource = strcat(path,file);
        end

        % Selection changed function: ModoButtonGroup
        function ModoButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup.SelectedObject;
            if app.DesencriptarButton.Value == true
                app.CargarimagenButton.Enable = false;
                app.CifrarButton_4.Enable = false;
                app.CargarimgenButton_2.Enable = true;
                app.DescifrarButton_4.Enable = true;
            end
            if app.EncriptarButton.Value == true
                app.CargarimagenButton.Enable = true;
                app.CifrarButton_4.Enable = true;
                app.CargarimgenButton_2.Enable = false;
                app.DescifrarButton_4.Enable = false;
            end
        end

        % Selection changed function: ModoButtonGroup_2
        function ModoButtonGroup_2SelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup_2.SelectedObject;
            if app.EncriptarButton_2.Value == true
                app.TextoclaroTextArea.Editable = true;
                app.TextocifradoTextArea.Editable = false;
                app.CifrarButton.Enable = true;
                app.DescifrarButton.Enable = false;
                app.AnalizarButton.Enable = false;
                app.ClaveEditField.Enable = true;
            end
            if app.DesencriptarButton_2.Value == true
                app.TextoclaroTextArea.Editable = false;
                app.TextocifradoTextArea.Editable = true;
                app.CifrarButton.Enable = false;
                app.DescifrarButton.Enable = true;
                app.AnalizarButton.Enable = false;
                app.ClaveEditField.Enable = true;
            end
            if app.AnlisisButton_6.Value == true
                app.TextoclaroTextArea.Editable = false;
                app.TextocifradoTextArea.Editable = true;
                app.CifrarButton.Enable = false;
                app.DescifrarButton.Enable = false;
                app.AnalizarButton.Enable = true;
                app.ClaveEditField.Enable = false;
            end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.TextoclaroTextArea.Value = '';
            app.TextocifradoTextArea.Value = '';
            app.ClaveEditField.Value = 17;
            app.AnalisisUITable.Data = [];
        end

        % Selection changed function: ModoButtonGroup_3
        function ModoButtonGroup_3SelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup_3.SelectedObject;
            if app.EncriptarButton_3.Value == true
                app.TextoclaroTextArea_2.Editable = true;
                app.TextocifradoTextArea_2.Editable = false;
                app.CifrarButton_2.Enable = true;
                app.DescifrarButton_2.Enable = false;
                app.AnalizarButton_2.Enable = false;
                app.ClaveAEditField.Enable = true;
                app.ClaveEditField_3.Enable = true;
            end
            if app.DesencriptarButton_3.Value == true
                app.TextoclaroTextArea_2.Editable = false;
                app.TextocifradoTextArea_2.Editable = true;
                app.CifrarButton_2.Enable = false;
                app.DescifrarButton_2.Enable = true;
                app.AnalizarButton_2.Enable = false;
                app.ClaveAEditField.Enable = true;
                app.ClaveEditField_3.Enable = true;
            end
            if app.AnlisisButton_7.Value == true
                app.TextoclaroTextArea_2.Editable = false;
                app.TextocifradoTextArea_2.Editable = true;
                app.CifrarButton_2.Enable = false;
                app.DescifrarButton_2.Enable = false;
                app.AnalizarButton_2.Enable = true;
                app.ClaveAEditField.Enable = false;
                app.ClaveEditField_3.Enable = false;
            end
        end

        % Button pushed function: LimpiarButton_2
        function LimpiarButton_2Pushed(app, event)
            app.TextoclaroTextArea_2.Value = '';
            app.TextocifradoTextArea_2.Value = '';
            app.ClaveAEditField.Value = 3;
            app.ClaveEditField_3.Value = 4;
            app.AnalisisUITable_2.Data = [];
        end

        % Cell edit callback: ClaveUITable
        function ClaveUITableCellEdit(app, event)
            %indices = event.Indices;
            newData = event.NewData;
            abet = "ABCDEFGHIJKLMNOPQRSTUVWXYZ";
            abet2 = replace(abet,newData,"");
            abet_ = regexprep(abet2,getGlobaly,'');
            if abet_ == ""
                abet_ = " ";
            end
            app.ClaveUITable.ColumnFormat = ({[] num2cell(char(abet_))});
            app.ClaveUITable.ColumnEditable = [false true];
        end

        % Display data changed function: ClaveUITable
        function ClaveUITableDisplayDataChanged(app, event)
            newDisplayData = app.ClaveUITable.DisplayData;
            str = strjoin(newDisplayData(:,2),' ');
            str2 = erase(erase(str," ")," ");
            str3 = convertStringsToChars(str2);
            str4 = cellstr(str3(:))';
            str5 = strjoin(str4,"|");
            setGlobaly(str5);
        end

        % Button pushed function: GenerarclaveButton
        function GenerarclaveButtonPushed(app, event)
            abet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            letters = split(abet,'')';
            letters = letters(~cellfun('isempty',letters));
            permut = randperm(26);
            list = {};
            for i=1:26
                list{i} = letters(permut(i));
            end
            app.ClaveUITable.Data(:,2) = list;
        end

        % Button pushed function: CifrarButton_5
        function CifrarButton_5Pushed(app, event)
            newDisplayData = app.ClaveUITable.DisplayData;
            str = strjoin(newDisplayData(:,2),' ');
            key = erase(erase(str,"clave")," ");
            message = app.TextoclaroTextArea_4.Value;
            app.TextocifradoTextArea_4.Value = cifrarS(app,message,key);
        end

        % Button pushed function: DescifrarButton_5
        function DescifrarButton_5Pushed(app, event)
            newDisplayData = app.ClaveUITable.DisplayData;
            str = strjoin(newDisplayData(:,2),' ');
            key = erase(erase(str,"clave")," ");
            message = app.TextocifradoTextArea_4.Value;
            app.TextoclaroTextArea_4.Value = descifrarS(app,message,key);
        end

        % Selection changed function: ModoButtonGroup_4
        function ModoButtonGroup_4SelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup_4.SelectedObject;
            if app.EncriptarButton_4.Value == true
                app.TextoclaroTextArea_4.Editable = true;
                app.TextocifradoTextArea_4.Editable = false;
                app.CifrarButton_5.Enable = true;
                app.DescifrarButton_5.Enable = false;
            end
            if app.DesencriptarButton_4.Value == true
                app.TextoclaroTextArea_4.Editable = false;
                app.TextocifradoTextArea_4.Editable = true;
                app.CifrarButton_5.Enable = false;
                app.DescifrarButton_5.Enable = true;
            end
        end

        % Button pushed function: LimpiarButton_3
        function LimpiarButton_3Pushed(app, event)
            app.TextoclaroTextArea_4.Value = '';
            app.TextocifradoTextArea_4.Value = '';
            app.ClaveUITable.Data = [num2cell(char('ABCDEFGHIJKLMNOPQRSTUVWXYZ'))', repmat(" ",26,1)];
        end

        % Button pushed function: CifrarButton_4
        function CifrarButton_4Pushed(app, event)
            src = app.Image.ImageSource;
            imgC = imread(src);
            [x, y, z] = size(imgC); %n_filas, n_columnas, n_color
            b = 8; 
            nFila = floor(x/b);
            bloqueFila = [b*ones(1,nFila), rem(x,b)];
            nCol = floor(y/b);
            bloqueCol = [b*ones(1,nCol), rem(y,b)];
            
            k = app.UITable_2.Data; % clave
            n = gcd(int8(mod(det(k),26)),26);
            if n == 1
                iv = randi([1 26],1,8); %vector inicial IV
                
                str2double(num2str(iv)')
                %app.vectorinicialIVEditField.Value = str2double(num2str(iv)');
                
                key = k; % la clave se convierte en una matriz de 3 dimensiones 
                         % para cifrar cada capa de imagenes a color
                key(:,:,2) = k;
                key(:,:,3) = k;
                
                if z>1
                    %imagen a color
                    ca = mat2cell(imgC,bloqueFila,bloqueCol,z); % imagen dividida 
                                                                % en bloques de 8x8
                    numPlotsR = size(ca,1);
                    numPlotsC = size(ca,2);
                    newImg = ca;
                    
                    final = [];
                    
                    for r = 1 : numPlotsR
                        for c = 1: numPlotsC
                            rgbBlock = ca{r,c};
                            m = newImg{r,c};
                            s = size(double(rgbBlock(:,:,1)));
                            if s(2) == 8
                                if app.vectorinicialIVCheckBox.Value == true
                                    % multiplicar vector inicial por la clave
                                    key(:,:,1) = mod(iv.*key(:,:,1),26); 
                                    key(:,:,2) = mod(iv.*key(:,:,2),26); 
                                    key(:,:,3) = mod(iv.*key(:,:,3),26);
                                end
                                % cifrado
                                m(:,:,1) = mod(double(rgbBlock(:,:,1))*key(:,:,1),256);
                                m(:,:,2) = mod(double(rgbBlock(:,:,2))*key(:,:,2),256);
                                m(:,:,3) = mod(double(rgbBlock(:,:,3))*key(:,:,3),256);
                                newImg{r,c} = m;
                            end
                            % se vuelven a unir los bloques con los
                            % datos ya cifrados
                            if c==1
                                final1 = newImg{r,c};
                            else
                                final1 = [final1 newImg{r,c}];
                            end
                        end
                        if r==1 
                            final = final1;
                        else
                            final = [final; final1];
                        end
                    end
                    app.Image_2.ImageSource = final;
                else
                    % imagen a blanco y negro
                    ca = mat2cell(imgC,bloqueFila,bloqueCol);
                    
                    numPlotsR = size(ca,1);
                    numPlotsC = size(ca,2);
                    newImg = ca;
                    
                    final = [];
                    
                    for r = 1 : numPlotsR
                        for c = 1: numPlotsC
                            rgbBlock = ca{r,c};
                            m = newImg{r,c};
                            s = size(double(rgbBlock));
                            if s(2) == 8
                                if app.vectorinicialIVCheckBox.Value == true
                                    k = mod(iv.*k,26);
                                end
                                m = mod(double(rgbBlock)*k,256);
                                newImg{r,c} = m;
                            end
                            if c==1
                                final1 = newImg{r,c};
                            else
                                final1 = [final1 newImg{r,c}];
                            end
                        end
                        if r==1
                            final = final1;
                        else
                            final = [final; final1];
                        end
                    end
                    im  =cat(3,final, final, final);
                    app.Image_2.ImageSource = im;
                    
                end
            else
                errordlg("la matriz debe ser inversible", "clave ilegal");
            end
        end

        % Button pushed function: AnalizarButton_2
        function AnalizarButton_2Pushed(app, event)
            msg = app.TextocifradoTextArea_2.Value;
            data = analisisAfin(app,msg);
            app.AnalisisUITable_2.Data = cell2table(data);
        end

        % Callback function
        function GenerarclaveButton_2Pushed(app, event)
            size = app.TClaveEditField.Value;
            while true % generar una matriz invertible
                keyData = randi(25, size);
                if gcd(int8(mod(det(keyData),26)),26) == 1
                    break;
                end
            end
            app.UITable.Data = keyData;
        end

        % Selection changed function: ModoButtonGroup_5
        function ModoButtonGroup_5SelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup_5.SelectedObject;
            if app.EncriptarButton_5.Value == true
                app.TextocifradoTextArea_3.Editable = false;
                app.TextoclaroTextArea_3.Editable = true;
                app.UITable.ColumnEditable = true;
                app.CifrarButton_3.Enable = true;
                app.DescifrarButton_3.Enable = false;
                app.AnlisisButton_3.Enable = false;
            end
            if app.DesencriptarButton_5.Value == true
                app.TextocifradoTextArea_3.Editable = true;
                app.TextoclaroTextArea_3.Editable = false;
                app.UITable.ColumnEditable = true;
                app.CifrarButton_3.Enable = false;
                app.DescifrarButton_3.Enable = true;
                app.AnlisisButton_3.Enable = false;
            end
            if app.AnlisisButton_8.Value == true
                app.TextocifradoTextArea_3.Editable = true;
                app.TextoclaroTextArea_3.Editable = true;
                app.UITable.ColumnEditable = false;
                app.CifrarButton_3.Enable = false;
                app.DescifrarButton_3.Enable = false;
                app.AnlisisButton_3.Enable = true;
            end
        end

        % Button pushed function: LimpiarButton_4
        function LimpiarButton_4Pushed(app, event)
            app.TextocifradoTextArea_3.Value = '';
            app.TextoclaroTextArea_3.Value = '';
            app.UITable.Data = zeros(2,2);
            app.TClaveEditField.Value = 2;
        end

        % Button pushed function: CifrarButton_Vigenere
        function CifrarButton_VigenerePushed(app, event)
            message = app.TextoclaroTextArea_Vigenere.Value;
            key = app.ClaveEditField_Vigenere.Value;
            app.TextocifradoTextArea_Vigenere.Value = cifrarV(app,message,key,'cifrar');
        end

        % Button pushed function: DescifrarButton_Vigenere
        function DescifrarButton_VigenerePushed(app, event)
            message = app.TextocifradoTextArea_Vigenere.Value;
            key = app.ClaveEditField_Vigenere.Value;
            app.TextoclaroTextArea_Vigenere.Value = cifrarV(app,message,key,'descif');
        end

        % Selection changed function: ModoButtonGroup_Vigenere
        function ModoButtonGroup_VigenereSelectionChanged(app, event)
            %selectedButton = app.ModoButtonGroup_Vigenere.SelectedObject;
            if app.EncriptarButton_Vigenere.Value == true
                app.TextoclaroTextArea_Vigenere.Editable = true;
                app.TextocifradoTextArea_Vigenere.Editable = false;
                app.CifrarButton_Vigenere.Enable = true;
                app.DescifrarButton_Vigenere.Enable = false;
                app.AnlisisButton_9.Enable = false;
            end
            if app.DesencriptarButton_Vigenere.Value == true
                app.TextoclaroTextArea_Vigenere.Editable = false;
                app.TextocifradoTextArea_Vigenere.Editable = true;
                app.CifrarButton_Vigenere.Enable = false;
                app.DescifrarButton_Vigenere.Enable = true;
                app.AnlisisButton_9.Enable = false;
            end
            if app.AnlisisButton_Vigenere.Value == true
                app.TextoclaroTextArea_Vigenere.Editable = false;
                app.TextocifradoTextArea_Vigenere.Editable = true;
                app.CifrarButton_Vigenere.Enable = false;
                app.DescifrarButton_Vigenere.Enable = false;
                app.AnlisisButton_9.Enable = true;
            end
        end

        % Button pushed function: AnlisisButton_9
        function AnlisisButton_9Pushed(app, event)
            cMessage = app.TextocifradoTextArea_Vigenere.Value;
            findedKey = analisisVigenere(app,cMessage);
            app.ClaveEditField_Vigenere.Value = findedKey;
            app.TextoclaroTextArea_Vigenere.Value = cifrarV(app,cMessage,findedKey,'descif');
        end

        % Button pushed function: LimpiarButton_Vigenere
        function LimpiarButton_VigenerePushed(app, event)
            app.TextocifradoTextArea_Vigenere.Value = '';
            app.TextoclaroTextArea_Vigenere.Value = '';
            app.ClaveEditField_Vigenere.Value = '';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            infoDespl;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            infoSust;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            infoAfin;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            infoHill;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            infoVige;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            infoHill;
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            img = imread("Lenna_(test_image).png");
            app.Image.ImageSource = "Lenna_(test_image).png";
        end

        % Callback function: Hyperlink_2
        function Hyperlink_2Clicked(app, event)
            img = imread("lenna_gris.png");
            app.Image.ImageSource = "lenna_gris.png";
        end

        % Callback function: Hyperlink_3
        function Hyperlink_3Clicked(app, event)
            img = imread("cuadros.jpg");
            app.Image.ImageSource = "cuadros.jpg";
        end

        % Callback function: Hyperlink_4
        function Hyperlink_4Clicked(app, event)
            img = imread("persona.jpg");
            app.Image.ImageSource = "persona.jpg";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DesplazamientoTab = uitab(app.TabGroup);
            app.DesplazamientoTab.Title = 'Desplazamiento';
            app.CifradoPanel = uipanel(app.DesplazamientoTab);
            app.CifradoPanel.Title = 'Cifrado';
            app.TextoclaroTextAreaLabel = uilabel(app.CifradoPanel);
            app.TextoclaroTextAreaLabel.Text = 'Texto claro';
            app.TextoclaroTextArea = uitextarea(app.CifradoPanel);
            app.TextoclaroTextArea.ValueChangedFcn = createCallbackFcn(app, @TextoclaroTextAreaValueChanged, true);
            app.TextocifradoTextAreaLabel = uilabel(app.CifradoPanel);
            app.TextocifradoTextAreaLabel.Text = {'Texto cifrado'; ''};
            app.TextocifradoTextArea = uitextarea(app.CifradoPanel);
            app.CifrarButton = uibutton(app.CifradoPanel, 'push');
            app.CifrarButton.ButtonPushedFcn = createCallbackFcn(app, @CifrarButtonPushed, true);
            app.CifrarButton.Text = {'Cifrar'; ''};
            app.DescifrarButton = uibutton(app.CifradoPanel, 'push');
            app.DescifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButtonPushed, true);
            app.DescifrarButton.Text = 'Descifrar';
            app.ClaveEditFieldLabel = uilabel(app.CifradoPanel);
            app.ClaveEditFieldLabel.Text = 'Clave';
            app.ClaveEditField = uieditfield(app.CifradoPanel, 'numeric');
            app.ClaveEditField.Limits = [0 25];
            app.LimpiarButton = uibutton(app.CifradoPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = {'Limpiar'; ''};
            app.AnlisisPanel = uipanel(app.DesplazamientoTab);
            app.AnlisisPanel.Title = 'Análisis';
            app.AnalisisUITable = uitable(app.AnlisisPanel);
            app.AnalisisUITable.ColumnName = {'Clave'; 'Texto claro'};
            app.AnalisisUITable.RowName = {};
            app.AnalisisUITable.ColumnEditable = true;
            app.AnalizarButton = uibutton(app.AnlisisPanel, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Text = 'Analizar';
            app.ModoButtonGroup_2 = uibuttongroup(app.DesplazamientoTab);
            app.ModoButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroup_2SelectionChanged, true);
            app.ModoButtonGroup_2.Title = 'Modo';
            app.EncriptarButton_2 = uiradiobutton(app.ModoButtonGroup_2);
            app.EncriptarButton_2.Text = 'Encriptar';
            app.EncriptarButton_2.Value = true;
            app.DesencriptarButton_2 = uiradiobutton(app.ModoButtonGroup_2);
            app.DesencriptarButton_2.Text = 'Desencriptar';
            app.AnlisisButton_6 = uiradiobutton(app.ModoButtonGroup_2);
            app.AnlisisButton_6.Text = 'Análisis';
            app.Button = uibutton(app.DesplazamientoTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.SustitucinTab = uitab(app.TabGroup);
            app.SustitucinTab.Title = 'Sustitución';
            app.ModoButtonGroup_4 = uibuttongroup(app.SustitucinTab);
            app.ModoButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroup_4SelectionChanged, true);
            app.ModoButtonGroup_4.Title = 'Modo';
            app.DesencriptarButton_4 = uiradiobutton(app.ModoButtonGroup_4);
            app.DesencriptarButton_4.Text = 'Desencriptar';
            app.EncriptarButton_4 = uiradiobutton(app.ModoButtonGroup_4);
            app.EncriptarButton_4.Text = 'Encriptar';
            app.EncriptarButton_4.Value = true;
            app.CifradoPanel_5 = uipanel(app.SustitucinTab);
            app.CifradoPanel_5.Title = 'Cifrado';
            app.TextoclaroTextArea_4Label = uilabel(app.CifradoPanel_5);
            app.TextoclaroTextArea_4Label.Text = 'Texto claro';
            app.TextoclaroTextArea_4 = uitextarea(app.CifradoPanel_5);
            app.TextocifradoTextArea_4Label = uilabel(app.CifradoPanel_5);
            app.TextocifradoTextArea_4Label.Text = {'Texto cifrado'; ''};
            app.TextocifradoTextArea_4 = uitextarea(app.CifradoPanel_5);
            app.CifrarButton_5 = uibutton(app.CifradoPanel_5, 'push');
            app.CifrarButton_5.ButtonPushedFcn = createCallbackFcn(app, @CifrarButton_5Pushed, true);
            app.CifrarButton_5.Text = {'Cifrar'; ''};
            app.DescifrarButton_5 = uibutton(app.CifradoPanel_5, 'push');
            app.DescifrarButton_5.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButton_5Pushed, true);
            app.DescifrarButton_5.Text = 'Descifrar';
            app.LimpiarButton_3 = uibutton(app.CifradoPanel_5, 'push');
            app.LimpiarButton_3.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_3Pushed, true);
            app.LimpiarButton_3.Text = {'Limpiar'; ''};
            app.ClavePanel = uipanel(app.SustitucinTab);
            app.ClavePanel.Title = 'Clave';
            app.ClaveUITable = uitable(app.ClavePanel);
            app.ClaveUITable.ColumnName = {'Letra'; 'Clave'};
            app.ClaveUITable.RowName = {};
            app.ClaveUITable.ColumnEditable = true;
            app.ClaveUITable.CellEditCallback = createCallbackFcn(app, @ClaveUITableCellEdit, true);
            app.ClaveUITable.DisplayDataChangedFcn = createCallbackFcn(app, @ClaveUITableDisplayDataChanged, true);
            app.GenerarclaveButton = uibutton(app.ClavePanel, 'push');
            app.GenerarclaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarclaveButtonPushed, true);
            app.GenerarclaveButton.Text = 'Generar clave';
            app.Button_4 = uibutton(app.SustitucinTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.AfnTab = uitab(app.TabGroup);
            app.AfnTab.Title = 'Afín';
            app.CifradoPanel_2 = uipanel(app.AfnTab);
            app.CifradoPanel_2.Title = 'Cifrado';
            app.TextoclaroTextArea_2Label = uilabel(app.CifradoPanel_2);
            app.TextoclaroTextArea_2Label.Text = 'Texto claro';
            app.TextoclaroTextArea_2 = uitextarea(app.CifradoPanel_2);
            app.TextocifradoTextArea_2Label = uilabel(app.CifradoPanel_2);
            app.TextocifradoTextArea_2Label.Text = {'Texto cifrado'; ''};
            app.TextocifradoTextArea_2 = uitextarea(app.CifradoPanel_2);
            app.CifrarButton_2 = uibutton(app.CifradoPanel_2, 'push');
            app.CifrarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CifrarButton_2Pushed, true);
            app.CifrarButton_2.Text = {'Cifrar'; ''};
            app.DescifrarButton_2 = uibutton(app.CifradoPanel_2, 'push');
            app.DescifrarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButton_2Pushed, true);
            app.DescifrarButton_2.Text = 'Descifrar';
            app.ClaveALabel = uilabel(app.CifradoPanel_2);
            app.ClaveALabel.Text = 'Clave        A';
            app.ClaveAEditField = uieditfield(app.CifradoPanel_2, 'numeric');
            app.ClaveAEditField.Limits = [0 25];
            app.ClaveAEditField.ValueChangedFcn = createCallbackFcn(app, @ClaveAEditFieldValueChanged, true);
            app.LimpiarButton_2 = uibutton(app.CifradoPanel_2, 'push');
            app.LimpiarButton_2.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_2Pushed, true);
            app.LimpiarButton_2.Text = {'Limpiar'; ''};
            app.BLabel = uilabel(app.CifradoPanel_2);
            app.BLabel.Text = 'B';
            app.ClaveEditField_3 = uieditfield(app.CifradoPanel_2, 'numeric');
            app.ClaveEditField_3.Limits = [0 25];
            app.AnlisisPanel_2 = uipanel(app.AfnTab);
            app.AnlisisPanel_2.Title = 'Análisis';
            app.AnalisisUITable_2 = uitable(app.AnlisisPanel_2);
            app.AnalisisUITable_2.ColumnName = {'A'; 'B'; 'Texto claro'};
            app.AnalisisUITable_2.RowName = {};
            app.AnalizarButton_2 = uibutton(app.AnlisisPanel_2, 'push');
            app.AnalizarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButton_2Pushed, true);
            app.AnalizarButton_2.Text = 'Analizar';
            app.ModoButtonGroup_3 = uibuttongroup(app.AfnTab);
            app.ModoButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroup_3SelectionChanged, true);
            app.ModoButtonGroup_3.Title = 'Modo';
            app.EncriptarButton_3 = uiradiobutton(app.ModoButtonGroup_3);
            app.EncriptarButton_3.Text = 'Encriptar';
            app.EncriptarButton_3.Value = true;
            app.DesencriptarButton_3 = uiradiobutton(app.ModoButtonGroup_3);
            app.DesencriptarButton_3.Text = 'Desencriptar';
            app.AnlisisButton_7 = uiradiobutton(app.ModoButtonGroup_3);
            app.AnlisisButton_7.Text = 'Análisis';
            app.Button_2 = uibutton(app.AfnTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.HillTextoTab = uitab(app.TabGroup);
            app.HillTextoTab.Title = 'Hill Texto';
            app.CifradoPanel_3 = uipanel(app.HillTextoTab);
            app.CifradoPanel_3.Title = 'Cifrado';
            app.TextoclaroTextArea_3Label = uilabel(app.CifradoPanel_3);
            app.TextoclaroTextArea_3Label.Text = 'Texto claro';
            app.TextoclaroTextArea_3 = uitextarea(app.CifradoPanel_3);
            app.TextocifradoTextArea_3Label = uilabel(app.CifradoPanel_3);
            app.TextocifradoTextArea_3Label.Text = {'Texto cifrado'; ''};
            app.TextocifradoTextArea_3 = uitextarea(app.CifradoPanel_3);
            app.CifrarButton_3 = uibutton(app.CifradoPanel_3, 'push');
            app.CifrarButton_3.ButtonPushedFcn = createCallbackFcn(app, @CifrarButton_3Pushed, true);
            app.CifrarButton_3.Text = {'Cifrar'; ''};
            app.DescifrarButton_3 = uibutton(app.CifradoPanel_3, 'push');
            app.DescifrarButton_3.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButton_3Pushed, true);
            app.DescifrarButton_3.Text = 'Descifrar';
            app.DimensindelamatrizLabel = uilabel(app.CifradoPanel_3);
            app.DimensindelamatrizLabel.Text = 'Dimensión de la matriz';
            app.TClaveEditField = uieditfield(app.CifradoPanel_3, 'numeric');
            app.TClaveEditField.Limits = [1 8];
            app.TClaveEditField.ValueChangedFcn = createCallbackFcn(app, @TClaveEditFieldValueChanged, true);
            app.TClaveEditField.Value = 2;
            app.AnlisisButton_3 = uibutton(app.CifradoPanel_3, 'push');
            app.AnlisisButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnlisisButton_3Pushed, true);
            app.AnlisisButton_3.Text = 'Análisis';
            app.UITable = uitable(app.CifradoPanel_3);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ClaveLabel_2 = uilabel(app.CifradoPanel_3);
            app.ClaveLabel_2.Text = 'Clave';
            app.LimpiarButton_4 = uibutton(app.CifradoPanel_3, 'push');
            app.LimpiarButton_4.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_4Pushed, true);
            app.LimpiarButton_4.Text = 'Limpiar';
            app.ModoButtonGroup_5 = uibuttongroup(app.HillTextoTab);
            app.ModoButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroup_5SelectionChanged, true);
            app.ModoButtonGroup_5.Title = 'Modo';
            app.EncriptarButton_5 = uiradiobutton(app.ModoButtonGroup_5);
            app.EncriptarButton_5.Text = 'Encriptar';
            app.EncriptarButton_5.Value = true;
            app.AnlisisButton_8 = uiradiobutton(app.ModoButtonGroup_5);
            app.AnlisisButton_8.Text = 'Análisis';
            app.DesencriptarButton_5 = uiradiobutton(app.ModoButtonGroup_5);
            app.DesencriptarButton_5.Text = 'Desencriptar';
            app.Button_3 = uibutton(app.HillTextoTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.HillImagenTab = uitab(app.TabGroup);
            app.HillImagenTab.Title = 'Hill Imagen';
            app.CifradoPanel_4 = uipanel(app.HillImagenTab);
            app.CifradoPanel_4.Title = 'Cifrado';
            app.CargarimagenButton = uibutton(app.CifradoPanel_4, 'push');
            app.CargarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagenButtonPushed, true);
            app.CargarimagenButton.Text = 'Cargar imagen';
            app.Image = uiimage(app.CifradoPanel_4);
            app.CifrarButton_4 = uibutton(app.CifradoPanel_4, 'push');
            app.CifrarButton_4.ButtonPushedFcn = createCallbackFcn(app, @CifrarButton_4Pushed, true);
            app.CifrarButton_4.Text = {'Cifrar'; ''};
            app.Image_2 = uiimage(app.CifradoPanel_4);
            app.ImagencifradaLabel = uilabel(app.CifradoPanel_4);
            app.ImagencifradaLabel.Text = 'Imagen cifrada';
            app.Label = uilabel(app.CifradoPanel_4);
            app.Label.Text = 'Se permite cargar imágenes a color o blanco y negro, en formatos png y jpg';
            app.ModoButtonGroup = uibuttongroup(app.HillImagenTab);
            app.ModoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroupSelectionChanged, true);
            app.ModoButtonGroup.Title = 'Modo';
            app.EncriptarButton = uiradiobutton(app.ModoButtonGroup);
            app.EncriptarButton.Text = 'Encriptar';
            app.EncriptarButton.Value = true;
            app.Button_6 = uibutton(app.HillImagenTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Hyperlink = uihyperlink(app.HillImagenTab);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = {'Lenna_(test_image).png'; ''};
            app.Hyperlink_2 = uihyperlink(app.HillImagenTab);
            app.Hyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_2Clicked, true);
            app.Hyperlink_2.Text = {'lenna_gris.png'; ''};
            app.Hyperlink_3 = uihyperlink(app.HillImagenTab);
            app.Hyperlink_3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_3Clicked, true);
            app.Hyperlink_3.Text = 'cuadros.jpg';
            app.Hyperlink_4 = uihyperlink(app.HillImagenTab);
            app.Hyperlink_4.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink_4Clicked, true);
            app.Hyperlink_4.Text = {'persona.jpg'; ''};
            app.Label_2 = uilabel(app.HillImagenTab);
            app.Label_2.Text = 'Imágenes de prueba: hacer click sobre alguno de los links para cargar una imagen predeterminada';
            app.ClavePanel_2 = uipanel(app.HillImagenTab);
            app.ClavePanel_2.Title = 'Clave';
            app.UITable_2 = uitable(app.ClavePanel_2);
            app.UITable_2.ColumnName = {''};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = true;
            app.DimensindelamatrizLabel_2 = uilabel(app.ClavePanel_2);
            app.DimensindelamatrizLabel_2.Text = 'dimensión de la matriz';
            app.TClaveEditField_2 = uieditfield(app.ClavePanel_2, 'numeric');
            app.TClaveEditField_2.Limits = [2 8];
            app.TClaveEditField_2.Editable = 'off';
            app.TClaveEditField_2.Value = 8;
            app.vectorinicialIVCheckBox = uicheckbox(app.ClavePanel_2);
            app.vectorinicialIVCheckBox.Text = ' vector inicial IV';
            app.VigenereTab = uitab(app.TabGroup);
            app.VigenereTab.Title = 'Vigenere';
            app.ModoButtonGroup_Vigenere = uibuttongroup(app.VigenereTab);
            app.ModoButtonGroup_Vigenere.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroup_VigenereSelectionChanged, true);
            app.ModoButtonGroup_Vigenere.Title = 'Modo';
            app.EncriptarButton_Vigenere = uiradiobutton(app.ModoButtonGroup_Vigenere);
            app.EncriptarButton_Vigenere.Text = 'Encriptar';
            app.EncriptarButton_Vigenere.Value = true;
            app.DesencriptarButton_Vigenere = uiradiobutton(app.ModoButtonGroup_Vigenere);
            app.DesencriptarButton_Vigenere.Text = 'Desencriptar';
            app.AnlisisButton_Vigenere = uiradiobutton(app.ModoButtonGroup_Vigenere);
            app.AnlisisButton_Vigenere.Text = 'Análisis';
            app.CifradoPanel_Vigenere = uipanel(app.VigenereTab);
            app.CifradoPanel_Vigenere.Title = 'Cifrado';
            app.TextoclaroTextArea_5Label = uilabel(app.CifradoPanel_Vigenere);
            app.TextoclaroTextArea_5Label.Text = 'Texto claro';
            app.TextoclaroTextArea_Vigenere = uitextarea(app.CifradoPanel_Vigenere);
            app.TextocifradoTextArea_5Label = uilabel(app.CifradoPanel_Vigenere);
            app.TextocifradoTextArea_5Label.Text = {'Texto cifrado'; ''};
            app.TextocifradoTextArea_Vigenere = uitextarea(app.CifradoPanel_Vigenere);
            app.CifrarButton_Vigenere = uibutton(app.CifradoPanel_Vigenere, 'push');
            app.CifrarButton_Vigenere.ButtonPushedFcn = createCallbackFcn(app, @CifrarButton_VigenerePushed, true);
            app.CifrarButton_Vigenere.Text = {'Cifrar'; ''};
            app.DescifrarButton_Vigenere = uibutton(app.CifradoPanel_Vigenere, 'push');
            app.DescifrarButton_Vigenere.ButtonPushedFcn = createCallbackFcn(app, @DescifrarButton_VigenerePushed, true);
            app.DescifrarButton_Vigenere.Text = 'Descifrar';
            app.LimpiarButton_Vigenere = uibutton(app.CifradoPanel_Vigenere, 'push');
            app.LimpiarButton_Vigenere.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButton_VigenerePushed, true);
            app.LimpiarButton_Vigenere.Text = {'Limpiar'; ''};
            app.ClaveEditField_4Label = uilabel(app.CifradoPanel_Vigenere);
            app.ClaveEditField_4Label.Text = 'Clave';
            app.ClaveEditField_Vigenere = uieditfield(app.CifradoPanel_Vigenere, 'text');
            app.AnlisisButton_9 = uibutton(app.CifradoPanel_Vigenere, 'push');
            app.AnlisisButton_9.ButtonPushedFcn = createCallbackFcn(app, @AnlisisButton_9Pushed, true);
            app.AnlisisButton_9.Text = 'Análisis';
            app.mximo15caracteresLabel = uilabel(app.CifradoPanel_Vigenere);
            app.mximo15caracteresLabel.Text = {'(máximo 15 caracteres)'; ''};
            app.Button_5 = uibutton(app.VigenereTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectoCriptografia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end